classdef planar_antenna_analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PLANARANTENNASLabel           matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        CalculateReceivedPowerButton  matlab.ui.control.Button
        LengthmEditField              matlab.ui.control.NumericEditField
        LengthmEditFieldLabel         matlab.ui.control.Label
        AntennaTypeLabel              matlab.ui.control.Label
        Side3mmEditField              matlab.ui.control.NumericEditField
        Side3mmEditFieldLabel         matlab.ui.control.Label
        Side2mmEditField              matlab.ui.control.NumericEditField
        Side2mmEditFieldLabel         matlab.ui.control.Label
        Side1mmEditField              matlab.ui.control.NumericEditField
        Side1mmEditFieldLabel         matlab.ui.control.Label
        GaindBEditField               matlab.ui.control.NumericEditField
        GaindBEditFieldLabel          matlab.ui.control.Label
        EnterTransmitterPowermWEditField  matlab.ui.control.NumericEditField
        EnterTransmitterPowermWEditFieldLabel  matlab.ui.control.Label
        ReceivedPowerWEditField       matlab.ui.control.EditField
        ReceivedPowerWEditFieldLabel  matlab.ui.control.Label
        RadiusmmEditField             matlab.ui.control.NumericEditField
        RadiusmmEditFieldLabel        matlab.ui.control.Label
        AntennaButton                 matlab.ui.control.Button
        ElevationPlotButton           matlab.ui.control.Button
        AzimuthalPlotButton           matlab.ui.control.Button
        HeightmmEditField             matlab.ui.control.NumericEditField
        HeightmmEditFieldLabel        matlab.ui.control.Label
        WidthmmEditField              matlab.ui.control.NumericEditField
        WidthmmEditFieldLabel         matlab.ui.control.Label
        DielectricConstantEditField   matlab.ui.control.NumericEditField
        DielectricConstantEditFieldLabel  matlab.ui.control.Label
        LengthmmEditField             matlab.ui.control.NumericEditField
        LengthmmEditFieldLabel        matlab.ui.control.Label
        Tree                          matlab.ui.container.Tree
        PatchAntennaNode              matlab.ui.container.TreeNode
        RectangularPatchAntennaNode   matlab.ui.container.TreeNode
        CircularPatchAntennaNode      matlab.ui.container.TreeNode
        TriangularPatchAntennaNode    matlab.ui.container.TreeNode
        SlotAntennaNode               matlab.ui.container.TreeNode
        RadiationPatternButton        matlab.ui.control.Button
        FrequencyofOperationGHzEditField  matlab.ui.control.NumericEditField
        FrequencyofOperationGHzEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.LengthmmEditField.Visible = 'off';
            app.WidthmmEditField.Visible = 'off';
            app.DielectricConstantEditField.Visible = 'off';
            app.HeightmmEditField.Visible = 'off';
            app.RadiusmmEditField.Visible='off';
            app.LengthmEditField.Visible = 'off';

            app.LengthmmEditFieldLabel.Visible = 'off';
            app.WidthmmEditFieldLabel.Visible = 'off';
            app.DielectricConstantEditFieldLabel.Visible = 'off';
            app.HeightmmEditFieldLabel.Visible = 'off';
            app.RadiusmmEditFieldLabel.Visible='off';
            app.LengthmEditFieldLabel.Visible = 'off';

            app.Side1mmEditField.Visible = 'off';
            app.Side1mmEditFieldLabel.Visible = 'off';
            app.Side2mmEditField.Visible = 'off';
            app.Side2mmEditFieldLabel.Visible = 'off';
            app.Side3mmEditField.Visible = 'off';
            app.Side3mmEditFieldLabel.Visible = 'off';

            selectedNodes = app.Tree.SelectedNodes;
            switch (selectedNodes.Text)
                case 'Rectangular Patch Antenna'
                    app.LengthmmEditField.Visible = 'on';
                    app.WidthmmEditField.Visible = 'on';
                    app.DielectricConstantEditField.Visible = 'on';
                    app.HeightmmEditField.Visible = 'on';

                    app.LengthmmEditFieldLabel.Visible = 'on';
                    app.WidthmmEditFieldLabel.Visible = 'on';
                    app.DielectricConstantEditFieldLabel.Visible = 'on';
                    app.HeightmmEditFieldLabel.Visible = 'on'; 
                case 'Circular Patch Antenna'
                    app.HeightmmEditField.Visible='on';
                    app.RadiusmmEditField.Visible='on';
                    app.HeightmmEditFieldLabel.Visible = 'on';
                    app.RadiusmmEditFieldLabel.Visible='on';
                    app.DielectricConstantEditField.Visible = 'on';
                    app.DielectricConstantEditFieldLabel.Visible = 'on';
                case 'Slot Antenna'
                    app.LengthmEditField.Visible = 'on';
                    app.WidthmmEditField.Visible = 'on';
                    app.LengthmEditFieldLabel.Visible = 'on';
                    app.WidthmmEditFieldLabel.Visible = 'on';   

                case 'Triangular Patch Antenna'
                    app.Side1mmEditField.Visible = 'on';
                    app.Side1mmEditFieldLabel.Visible = 'on';
                    app.Side2mmEditField.Visible = 'on';
                    app.Side2mmEditFieldLabel.Visible = 'on';
                    app.Side3mmEditField.Visible = 'on';
                    app.Side3mmEditFieldLabel.Visible = 'on';
                    app.DielectricConstantEditField.Visible = 'on';
                    app.DielectricConstantEditFieldLabel.Visible = 'on';
            end

        end

        % Button pushed function: AntennaButton
        function AntennaButtonPushed(app, event)
            %getting values
            freq = app.FrequencyofOperationGHzEditField.Value;
            freq = freq*1000000000;

            p=(app.EnterTransmitterPowermWEditField.Value)*0.001;
            pr=p*(3e8/freq)^2/3950;
            app.ReceivedPowerWEditField.Value=num2str(ceil(pr));
            
            selectedNodes = app.Tree.SelectedNodes;
             switch (selectedNodes.Text)
                 case'Rectangular Patch Antenna'   
                    length = app.LengthmmEditField.Value;
                    width = app.WidthmmEditField.Value;
                    height = app.HeightmmEditField.Value;
                    dielectric_coeff = app.DielectricConstantEditField.Value;

                    
                    p1 = patchMicrostrip;
                    p1.Length = length*0.001;
                    p1.Width = width*0.001;
                    p1.Height = height*0.001;
                    p1.GroundPlaneLength = 80e-3;
                    p1.GroundPlaneWidth = 60e-3;
                    p1.FeedOffset = [5.5e-3 0];

                    p1.Substrate = dielectric(Name="Dielectric Substrate",EpsilonR=dielectric_coeff);

                    pb1 = pcbStack(p1);
                    pb1.FeedDiameter = sqrt(2)*1e-3;
                    pb1.FeedViaModel = "square";
                    figure
                    show(pb1);
                 case'Circular Patch Antenna'
                     radius=(app.RadiusmmEditField.Value)*0.001;
                     height=(app.HeightmmEditField.Value)*0.001;
                     dielectric_coeff = app.DielectricConstantEditField.Value;
                     patchAntenna=patchMicrostripCircular('Radius', radius, 'Height', height, 'Substrate', dielectric('Name', 'Dielectric Substrate', 'EpsilonR', dielectric_coeff, 'LossTangent', 0.02));
                     figure;
                     show(patchAntenna);

                 case 'Triangular Patch Antenna'
                     side1 = (app.Side1mmEditField.Value) * 0.001;
                     side2 = (app.Side2mmEditField.Value) * 0.001;
                     side3 = (app.Side3mmEditField.Value) * 0.001;
                     dielectric_coeff = app.DielectricConstantEditField.Value;
                     side = [side1 , side2, side3];

                     p1 = patchMicrostripTriangular(Side = side);

                     p1.GroundPlaneLength = 80e-3;
                     p1.GroundPlaneWidth = 60e-3;
                     p1.FeedOffset = [5.5e-3 0];

                     p1.Substrate = dielectric(Name="Dielectric Substrate",EpsilonR=dielectric_coeff);
                     show(p1)

                 case'Slot Antenna'
                    length=(app.LengthmEditField.Value);
                    width=(app.WidthmmEditField.Value)*0.001;
                    slotantenna=slot('Length', length, 'Width', width, 'GroundPlaneLength',1.5000 , 'GroundPlaneWidth',1.5000);
                    figure;
                    show(slotantenna);

              
             end

        end

        % Button pushed function: RadiationPatternButton
        function RadiationPatternButtonPushed(app, event)
            %getting values
            freq = app.FrequencyofOperationGHzEditField.Value;
            freq = freq*1000000000;
            app.GaindBEditField.Visible = 'on';
            app.GaindBEditFieldLabel.Visible = 'on';
                   
            selectedNodes = app.Tree.SelectedNodes;
            switch (selectedNodes.Text)
                case 'Rectangular Patch Antenna'
                    length = app.LengthmmEditField.Value;
                    width = app.WidthmmEditField.Value;
                    height = app.HeightmmEditField.Value;
                    dielectric_coeff = app.DielectricConstantEditField.Value;

                    
                    p1 = patchMicrostrip;
                    p1.Length = length*0.001;
                    p1.Width = width*0.001;
                    p1.Height = height*0.001;
                    p1.GroundPlaneLength = 80e-3;
                    p1.GroundPlaneWidth = 60e-3;
                    p1.FeedOffset = [5.5e-3 0];

                    p1.Substrate = dielectric(Name="material1",EpsilonR=dielectric_coeff);

                    pb1 = pcbStack(p1);
                    pb1.FeedDiameter = sqrt(2)*1e-3;
                    pb1.FeedViaModel = "square";
                    figure
                    pattern(pb1,freq);

                    [gain, azimuth, elevation] = pattern(pb1,freq, "Type", "gain");
                    v1 = max(gain);
                    rec_gain = v1(1,1);     %Receiver Antenna Gain 
                    gain_db = 10.^(rec_gain/10);
                    app.GaindBEditField.Value=gain_db;
                case 'Circular Patch Antenna'
                    radius=(app.RadiusmmEditField.Value)*0.001;
                    height=(app.HeightmmEditField.Value)*0.001;
                    dielectric_coeff = app.DielectricConstantEditField.Value;
                    patchAntenna=patchMicrostripCircular('Radius', radius, 'Height', height, 'Substrate', dielectric('Name', 'Dielectric Substrate', 'EpsilonR', dielectric_coeff, 'LossTangent', 0.02));
                    figure;
                    pattern(patchAntenna,freq);
                    [gain, azimuth, elevation] = pattern(patchAntenna,(freq), "Type", "gain");
                    v1 = max(gain);
                    rec_gain = v1(1,1);     %Receiver Antenna Gain 
                    gain_db = 10.^(rec_gain/10);
                    app.GaindBEditField.Value=gain_db;

                case 'Triangular Patch Antenna'
                     side1 = (app.Side1mmEditField.Value) * 0.001;
                     side2 = (app.Side2mmEditField.Value) * 0.001;
                     side3 = (app.Side3mmEditField.Value) * 0.001;
                     dielectric_coeff = app.DielectricConstantEditField.Value;
                     side = [side1 , side2, side3];

                     p1 = patchMicrostripTriangular(Side = side);

                     p1.GroundPlaneLength = 80e-3;
                     p1.GroundPlaneWidth = 60e-3;
                     p1.FeedOffset = [5.5e-3 0];

                     p1.Substrate = dielectric(Name="Dielectric Substrate",EpsilonR=dielectric_coeff);
                     pattern(p1,freq);
                     [gain, azimuth, elevation] = pattern(p1,(freq), "Type", "gain");
                     v1 = max(gain);
                     rec_gain = v1(1,1);     %Receiver Antenna Gain 
                     gain_db = 10.^(rec_gain/10);
                     app.GaindBEditField.Value=gain_db;

                case'Slot Antenna'
                    length=(app.LengthmEditField.Value);
                    width=(app.WidthmmEditField.Value)*0.001;
                    slotantenna=slot('Length', length, 'Width', width, 'GroundPlaneLength',1.5000 , 'GroundPlaneWidth',1.5000);
                    figure;
                    pattern(slotantenna,freq);
                    [gain, azimuth, elevation] = pattern(slotantenna,(freq), "Type", "gain");
                    v1 = max(gain);
                    rec_gain = v1(1,1);     %Receiver Antenna Gain 
                    gain_db = 10.^(rec_gain/10);
                    app.GaindBEditField.Value=gain_db;
                    

            end
                                           

        end

        % Button pushed function: AzimuthalPlotButton
        function AzimuthalPlotButtonPushed(app, event)
            %getting values
            freq = app.FrequencyofOperationGHzEditField.Value;
            freq = freq*1000000000;

            p=(app.EnterTransmitterPowermWEditField.Value)*0.001;
            pr=p*(3e8/freq)^2/3950;
            app.ReceivedPowerWEditField.Value=num2str(ceil(pr));

            selectedNodes = app.Tree.SelectedNodes;
               switch (selectedNodes.Text) 
                   case 'Rectangular Patch Antenna'
                     length=app.LengthmmEditField.Value;
                     width = app.WidthmmEditField.Value;
                     height = app.HeightmmEditField.Value;
                     dielectric_coeff = app.DielectricConstantEditField.Value;
                     p1 = patchMicrostrip;
                     p1.Length = length*0.001;
                     p1.Width = width*0.001;
                     p1.Height = height*0.001;
                     p1.GroundPlaneLength = 80e-3;
                     p1.GroundPlaneWidth = 60e-3;
                     p1.FeedOffset = [5.5e-3 0];
                     p1.Substrate = dielectric(Name="material1",EpsilonR=dielectric_coeff);

                     pb1 = pcbStack(p1);
                     pb1.FeedDiameter = sqrt(2)*1e-3;
                     pb1.FeedViaModel = "square";
                     figure
                     patternAzimuth(pb1,freq);
                   case 'Circular Patch Antenna'
                          radius=(app.RadiusmmEditField.Value)*0.001;
                           height=(app.HeightmmEditField.Value)*0.001;
                           dielectric_coeff = app.DielectricConstantEditField.Value;
                           patchAntenna=patchMicrostripCircular('Radius', radius, 'Height', height, 'Substrate', dielectric('Name', 'Dielectric Substrate', 'EpsilonR', dielectric_coeff, 'LossTangent', 0.02));
                           figure;
                           patternAzimuth(patchAntenna,freq);

                   case 'Triangular Patch Antenna'
                     side1 = (app.Side1mmEditField.Value) * 0.001;
                     side2 = (app.Side2mmEditField.Value) * 0.001;
                     side3 = (app.Side3mmEditField.Value) * 0.001;
                     dielectric_coeff = app.DielectricConstantEditField.Value;
                     side = [side1 , side2, side3];

                     p1 = patchMicrostripTriangular(Side = side);

                     p1.GroundPlaneLength = 80e-3;
                     p1.GroundPlaneWidth = 60e-3;
                     p1.FeedOffset = [5.5e-3 0];

                     p1.Substrate = dielectric(Name="Dielectric Substrate",EpsilonR=dielectric_coeff);
                     patternAzimuth(p1,freq);

                   case'Slot Antenna'
                    length=(app.LengthmmEditField.Value);
                    width=(app.WidthmmEditField.Value)*0.001;
                    slotantenna=slot('Length', length, 'Width', width, 'GroundPlaneLength',1.5000 , 'GroundPlaneWidth',1.5000);
                    figure;
                    patternAzimuth(slotantenna,freq);          
               end

        end

        % Button pushed function: ElevationPlotButton
        function ElevationPlotButtonPushed(app, event)
            %getting values
            freq = app.FrequencyofOperationGHzEditField.Value;
            freq = freq*1000000000;

            p=(app.EnterTransmitterPowermWEditField.Value)*0.001;
            pr=p*(3e8/freq)^2/3950;
            app.ReceivedPowerWEditField.Value=num2str(ceil(pr));
                 
            selectedNodes = app.Tree.SelectedNodes;
                 switch(selectedNodes.Text)
                     case 'Rectangular Patch Antenna'
                        length = app.LengthmmEditField.Value;
                        width = app.WidthmmEditField.Value;
                        height = app.HeightmmEditField.Value;
                        dielectric_coeff = app.DielectricConstantEditField.Value;

                    
                        p1 = patchMicrostrip;
                        p1.Length = length*0.001;
                        p1.Width = width*0.001;
                        p1.Height = height*0.001;
                        p1.GroundPlaneLength = 80e-3;
                        p1.GroundPlaneWidth = 60e-3;
                        p1.FeedOffset = [5.5e-3 0];
                        p1.Substrate = dielectric(Name="material1",EpsilonR=dielectric_coeff);

                        pb1 = pcbStack(p1);
                        pb1.FeedDiameter = sqrt(2)*1e-3;
                        pb1.FeedViaModel = "square";
                        figure
                        patternElevation(pb1,freq);
                     case 'Circular Patch Antenna'
                           radius=(app.RadiusmmEditField.Value)*0.001;
                           height=(app.HeightmmEditField.Value)*0.001;
                           dielectric_coeff = app.DielectricConstantEditField.Value;
                           patchAntenna=patchMicrostripCircular('Radius', radius, 'Height', height, 'Substrate', dielectric('Name', 'Dielectric Substrate', 'EpsilonR', dielectric_coeff, 'LossTangent', 0.02));
                           figure;
                           patternElevation(patchAntenna,freq);
                         
                     case 'Triangular Patch Antenna'
                         side1 = (app.Side1mmEditField.Value) * 0.001;
                        side2 = (app.Side2mmEditField.Value) * 0.001;
                        side3 = (app.Side3mmEditField.Value) * 0.001;
                        dielectric_coeff = app.DielectricConstantEditField.Value;
                        side = [side1 , side2, side3];

                        p1 = patchMicrostripTriangular(Side = side);

                        p1.GroundPlaneLength = 80e-3;
                        p1.GroundPlaneWidth = 60e-3;
                        p1.FeedOffset = [5.5e-3 0];

                        p1.Substrate = dielectric(Name="Dielectric Substrate",EpsilonR=dielectric_coeff);
                        patternElevation(p1,freq);

                     case'Slot Antenna'
                      length=(app.LengthmEditField.Value);
                      width=(app.WidthmmEditField.Value)*0.001;   
                      slotantenna=slot('Length', length, 'Width', width, 'GroundPlaneLength',1.5000 , 'GroundPlaneWidth',1.5000);
                      figure;
                      patternElevation(slotantenna,freq);
                 end

        end

        % Button pushed function: CalculateReceivedPowerButton
        function CalculateReceivedPowerButtonPushed(app, event)
            app.ReceivedPowerWEditField.Visible = 'on';
            app.ReceivedPowerWEditFieldLabel.Visible = 'on'
            freq = app.FrequencyofOperationGHzEditField.Value;
            freq = freq*1000000000;
            p=(app.EnterTransmitterPowermWEditField.Value)*0.001;
            pr=p*(3e8/freq)^2/3950;
            app.ReceivedPowerWEditField.Value=num2str(pr);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FrequencyofOperationGHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyofOperationGHzEditFieldLabel.Text = 'Frequency of Operation (GHz)';
            app.FrequencyofOperationGHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiationPatternButton = uibutton(app.UIFigure, 'push');
            app.RadiationPatternButton.ButtonPushedFcn = createCallbackFcn(app, @RadiationPatternButtonPushed, true);
            app.RadiationPatternButton.Text = 'Radiation Pattern';
            app.Tree = uitree(app.UIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Editable = 'on';
            app.PatchAntennaNode = uitreenode(app.Tree);
            app.PatchAntennaNode.Text = 'Patch Antenna';
            app.RectangularPatchAntennaNode = uitreenode(app.PatchAntennaNode);
            app.RectangularPatchAntennaNode.Text = 'Rectangular Patch Antenna';
            app.CircularPatchAntennaNode = uitreenode(app.PatchAntennaNode);
            app.CircularPatchAntennaNode.Text = 'Circular Patch Antenna';
            app.TriangularPatchAntennaNode = uitreenode(app.PatchAntennaNode);
            app.TriangularPatchAntennaNode.Text = 'Triangular Patch Antenna';
            app.SlotAntennaNode = uitreenode(app.Tree);
            app.SlotAntennaNode.Text = 'Slot Antenna';
            app.LengthmmEditFieldLabel = uilabel(app.UIFigure);
            app.LengthmmEditFieldLabel.Visible = 'off';
            app.LengthmmEditFieldLabel.Text = 'Length (mm)';
            app.LengthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthmmEditField.Limits = [1 Inf];
            app.LengthmmEditField.Visible = 'off';
            app.LengthmmEditField.Value = 1;
            app.DielectricConstantEditFieldLabel = uilabel(app.UIFigure);
            app.DielectricConstantEditFieldLabel.Visible = 'off';
            app.DielectricConstantEditFieldLabel.Text = 'Dielectric Constant';
            app.DielectricConstantEditField = uieditfield(app.UIFigure, 'numeric');
            app.DielectricConstantEditField.Limits = [1 Inf];
            app.DielectricConstantEditField.Visible = 'off';
            app.DielectricConstantEditField.Value = 1;
            app.WidthmmEditFieldLabel = uilabel(app.UIFigure);
            app.WidthmmEditFieldLabel.Visible = 'off';
            app.WidthmmEditFieldLabel.Text = 'Width (mm)';
            app.WidthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthmmEditField.Limits = [1 Inf];
            app.WidthmmEditField.Visible = 'off';
            app.WidthmmEditField.Value = 1;
            app.HeightmmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightmmEditFieldLabel.Visible = 'off';
            app.HeightmmEditFieldLabel.Text = 'Height (mm)';
            app.HeightmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightmmEditField.Limits = [1 Inf];
            app.HeightmmEditField.Visible = 'off';
            app.HeightmmEditField.Value = 1;
            app.AzimuthalPlotButton = uibutton(app.UIFigure, 'push');
            app.AzimuthalPlotButton.ButtonPushedFcn = createCallbackFcn(app, @AzimuthalPlotButtonPushed, true);
            app.AzimuthalPlotButton.Text = 'Azimuthal Plot';
            app.ElevationPlotButton = uibutton(app.UIFigure, 'push');
            app.ElevationPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ElevationPlotButtonPushed, true);
            app.ElevationPlotButton.Text = 'Elevation Plot';
            app.AntennaButton = uibutton(app.UIFigure, 'push');
            app.AntennaButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaButtonPushed, true);
            app.AntennaButton.Text = 'Antenna';
            app.RadiusmmEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusmmEditFieldLabel.Visible = 'off';
            app.RadiusmmEditFieldLabel.Text = 'Radius (mm)';
            app.RadiusmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusmmEditField.Limits = [1 Inf];
            app.RadiusmmEditField.Visible = 'off';
            app.RadiusmmEditField.Value = 1;
            app.ReceivedPowerWEditFieldLabel = uilabel(app.UIFigure);
            app.ReceivedPowerWEditFieldLabel.Visible = 'off';
            app.ReceivedPowerWEditFieldLabel.Text = 'Received Power (W)';
            app.ReceivedPowerWEditField = uieditfield(app.UIFigure, 'text');
            app.ReceivedPowerWEditField.Visible = 'off';
            app.EnterTransmitterPowermWEditFieldLabel = uilabel(app.UIFigure);
            app.EnterTransmitterPowermWEditFieldLabel.Text = 'Enter Transmitter Power (mW)';
            app.EnterTransmitterPowermWEditField = uieditfield(app.UIFigure, 'numeric');
            app.GaindBEditFieldLabel = uilabel(app.UIFigure);
            app.GaindBEditFieldLabel.Visible = 'off';
            app.GaindBEditFieldLabel.Text = 'Gain (dB)';
            app.GaindBEditField = uieditfield(app.UIFigure, 'numeric');
            app.GaindBEditField.Visible = 'off';
            app.Side1mmEditFieldLabel = uilabel(app.UIFigure);
            app.Side1mmEditFieldLabel.Visible = 'off';
            app.Side1mmEditFieldLabel.Text = 'Side 1 (mm)';
            app.Side1mmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Side1mmEditField.Limits = [1 Inf];
            app.Side1mmEditField.Visible = 'off';
            app.Side1mmEditField.Value = 1;
            app.Side2mmEditFieldLabel = uilabel(app.UIFigure);
            app.Side2mmEditFieldLabel.Visible = 'off';
            app.Side2mmEditFieldLabel.Text = 'Side 2 (mm)';
            app.Side2mmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Side2mmEditField.Limits = [1 Inf];
            app.Side2mmEditField.Visible = 'off';
            app.Side2mmEditField.Value = 1;
            app.Side3mmEditFieldLabel = uilabel(app.UIFigure);
            app.Side3mmEditFieldLabel.Visible = 'off';
            app.Side3mmEditFieldLabel.Text = 'Side 3 (mm)';
            app.Side3mmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Side3mmEditField.Limits = [1 Inf];
            app.Side3mmEditField.Visible = 'off';
            app.Side3mmEditField.Value = 1;
            app.AntennaTypeLabel = uilabel(app.UIFigure);
            app.AntennaTypeLabel.Text = 'Antenna Type:';
            app.LengthmEditFieldLabel = uilabel(app.UIFigure);
            app.LengthmEditFieldLabel.Visible = 'off';
            app.LengthmEditFieldLabel.Text = 'Length (m)';
            app.LengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthmEditField.Limits = [1 Inf];
            app.LengthmEditField.Visible = 'off';
            app.LengthmEditField.Value = 1;
            app.CalculateReceivedPowerButton = uibutton(app.UIFigure, 'push');
            app.CalculateReceivedPowerButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateReceivedPowerButtonPushed, true);
            app.CalculateReceivedPowerButton.Text = 'Calculate Received Power';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.PLANARANTENNASLabel = uilabel(app.UIFigure);
            app.PLANARANTENNASLabel.Text = 'PLANAR ANTENNAS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = planar_antenna_analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end